classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        TextArea_info    matlab.ui.control.TextArea
        TextArea_2Label  matlab.ui.control.Label
        TextArea         matlab.ui.control.TextArea
        Label_2          matlab.ui.control.Label
        EditField        matlab.ui.control.EditField
        Label            matlab.ui.control.Label
        ButtonLoad       matlab.ui.control.Button
        ButtonBrowser    matlab.ui.control.Button
        UIAxes           matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        net % Description
        StrArray;
        Iin;
        StrArrayNew;
    end     
    methods (Access = private)

        function logRefresh_func(app, StrArrayNew)
            if length(app.StrArray) >=5
                app.StrArray={};
            end
            app.StrArray = [app.StrArray, StrArrayNew];
            app.TextArea.Value=app.StrArray;
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            net = load("net.mat");
            app.net = app.net;
        end

        % Button pushed function: ButtonBrowser
        function ButtonBrowserPushed(app, event)
            [filename , pathname] = uigetfile({'*.bmp;*.png;*.jpg;'});
            if ~isequal(filename,0)
                app.EditField.Value = fullfile(pathname, filename);
                app.StrArrayNew = {'圖像瀏覽完成'};
                logRefresh_func(app, app.StrArrayNew);
            else
                app.StrArrayNew = {'圖像瀏覽失敗'};
                logRefresh_func(app, app.StrArrayNew);
                return
            end
        end

        % Button pushed function: ButtonLoad
        function ButtonLoadPushed(app, event)
            try
                app.Iin = imread(app.EditField.Value);
                imshow(app.Iin,'parent',app.UIAxes);
                app.StrArrayNew = {'圖像讀入完成'};
                logRefresh_func(app, app.StrArrayNew);
                YPred = classify(net, app.Iin);
                app.TextArea_info.Value = YPred;
                app.StrArrayNew = YPred;
                
            catch
                app.StrArrayNew = {'圖像讀入失敗'};
                logRefresh_func(app, app.StrArrayNew);
            end
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            
        end

        % Value changed function: TextArea_info
        function TextArea_infoValueChanged(app, event)
            value = app.TextArea_info.Value;
            
        end

        % Value changed function: TextArea
        function TextAreaValueChanged(app, event)
            value = app.TextArea.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '圖檔顯示')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.ButtonBrowser = uibutton(app.UIFigure, 'push');
            app.ButtonBrowser.ButtonPushedFcn = createCallbackFcn(app, @ButtonBrowserPushed, true);
            app.ButtonBrowser.Text = '瀏覽';
            app.ButtonLoad = uibutton(app.UIFigure, 'push');
            app.ButtonLoad.ButtonPushedFcn = createCallbackFcn(app, @ButtonLoadPushed, true);
            app.ButtonLoad.Text = '預測';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '選擇圖檔';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '訊息區';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.ValueChangedFcn = createCallbackFcn(app, @TextAreaValueChanged, true);
            app.TextArea_2Label = uilabel(app.UIFigure);
            app.TextArea_2Label.Text = '類別';
            app.TextArea_info = uitextarea(app.UIFigure);
            app.TextArea_info.ValueChangedFcn = createCallbackFcn(app, @TextArea_infoValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end