classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        TextArea_info  matlab.ui.control.TextArea
        Label_4        matlab.ui.control.Label
        ButtonLoad     matlab.ui.control.Button
        ButtonBrowser  matlab.ui.control.Button
        EditField      matlab.ui.control.EditField
        Label_3        matlab.ui.control.Label
        TextArea       matlab.ui.control.TextArea
        Label_2        matlab.ui.control.Label
        UIAxes         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        net % Description
        StrArray;
        Iin;
    end


    methods (Access = private)
        
        function logRefresh_func(app, StrArrayNew)
            if length(app.StrArray) >=5
                app.StrArray={};
            end
            app.StrArray = [app.StrArray, StrArrayNew];
            app.TextArea_info.Value=app.StrArray;
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
          net = load('net.mat');
          app.net = net.net;    
        end

        % Callback function
        function ImageClicked(app, event)


        end

        % Value changed function: TextArea
        function TextAreaValueChanged(app, event)
            value = app.TextArea.Value;
        end

        % Button pushed function: ButtonBrowser
        function ButtonBrowserPushed(app, event)
            [filename, pathname] = uigetfile({'*.bmp;*.png;*.jpg;'});
            if ~isequal(filename,0)
                app.EditField.Value = fullfile(pathname, filename);
                StrArrayNew = {'圖像瀏覽完成'};
                logRefresh_func(app, StrArrayNew);
            else
                StrArrayNew = {'圖像瀏覽失敗'};
                logRefresh_func(app, StrArrayNew);                
                return
            end
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)

        end

        % Button pushed function: ButtonLoad
        function ButtonLoadPushed(app, event)
            try
                app.Iin = imread(app.EditField.Value);
                imshow(app.Iin,'Parent',app.UIAxes);
                StrArrayNew={'圖像讀入完成'};
                logRefresh_func(app, StrArrayNew);   
                YPred = classify(app.net, app.Iin);
                app.TextArea.Value = YPred;                
            catch 
                StrArrayNew={'圖像讀入失敗'};
                logRefresh_func(app, StrArrayNew);                 
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '圖檔顯示')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '類別';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.ValueChangedFcn = createCallbackFcn(app, @TextAreaValueChanged, true);
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '選擇圖檔';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.ButtonBrowser = uibutton(app.UIFigure, 'push');
            app.ButtonBrowser.ButtonPushedFcn = createCallbackFcn(app, @ButtonBrowserPushed, true);
            app.ButtonBrowser.Text = '瀏覽';
            app.ButtonLoad = uibutton(app.UIFigure, 'push');
            app.ButtonLoad.ButtonPushedFcn = createCallbackFcn(app, @ButtonLoadPushed, true);
            app.ButtonLoad.Text = '讀入後預測';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '訊息區';
            app.TextArea_info = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end